classdef audioplayerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        InitialsEditField          matlab.ui.control.EditField
        InitialsEditFieldLabel     matlab.ui.control.Label
        MhzEditField               matlab.ui.control.NumericEditField
        MhzEditFieldLabel          matlab.ui.control.Label
        AngleEditField             matlab.ui.control.NumericEditField
        AngleEditFieldLabel        matlab.ui.control.Label
        DoneButton                 matlab.ui.control.Button
        GraphButton                matlab.ui.control.Button
        PickFileButton             matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        AudioChannelDropDown       matlab.ui.control.DropDown
        AudioChannelDropDownLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        y               
        player
        channel
        Fs
        vSpectrogram
        angle
        f_source
        sound_speed
        time
        file_path
        name
        initials
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: AudioChannelDropDown
        function AudioChannelDropDownValueChanged(app, event)
            value = app.AudioChannelDropDown.Value;
            if (strcmp('Channel 1', value))
                app.channel = 1;
            else 
                app.channel = 2;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.player = audioplayer(app.y(:,app.channel)*3.5, app.Fs);
            play(app.player);
        end

        % Button pushed function: PickFileButton
        function PickFileButtonPushed(app, event)
            app.sound_speed = 1540;
            app.angle = 60;
            app.f_source = 5.0 * 1e6;
            
            [file, path] = uigetfile('*.mp4;*.m4a;*.mp3;*.wav;*.avi');
            app.file_path = [path, file];
            app.name = [file];
            [app.y, app.Fs] = audioread(app.file_path);
            app.player = audioplayer(app.y(:,1), app.Fs);
            app.PickFileButton.Text = file;
            app.channel = 1;
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player)
        end

        % Drop down opening function: AudioChannelDropDown
        function AudioChannelDropDownOpening(app, event)

        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            [s,f,t] = stft(app.y(:,app.channel),app.Fs,'Window',kaiser(256*4,5),'OverlapLength',223*4,'FFTLength',1024*4);
            f = velocity_Envelope(7, 0.4, s, f);
            app.vSpectrogram = 100*f*app.sound_speed/(2*app.f_source*cosd(app.angle));
            app.time = t;
            
            plot(app.UIAxes, app.time, app.vSpectrogram)
            app.UIAxes.XScale
            app.UIAxes.Title.String = 'Velocity Envelope Over Time';
            app.UIAxes.XLabel.String = 'Time (seconds)';
            app.UIAxes.YLabel.String = 'Velocity (cm/s)';
            app.UIAxes.Toolbar.Visible = 'on';
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            value = app.AngleEditField.Value;
            app.angle = value;
        end

        % Value changed function: MhzEditField
        function MhzEditFieldValueChanged(app, event)
            value = app.MhzEditField.Value;
            app.f_source = value * 1e6;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            fig = uifigure;
            selection = uiconfirm(fig,'Would you like to Save?','Output',...
                        'Options',{'Yes', 'No (Close App)', 'Cancel'}, ...
                        'DefaultOption', 3, 'CancelOption', 3, 'Icon','warning');
            close(fig)
            if strcmp(selection, 'Yes')
                file_name = strcat(app.name(1:end-4), '_Velocity', app.initials, '.csv');
                if (isfile(file_name))
                    answer = questdlg('Found existing data, would you like to overwrite?', ...
	                    'Overwrite Data Warning', ...
	                     'Yes','No', 'No');
                    if strcmp(answer, 'No')
                        return;
                    end
                end
                writematrix(cat(2, app.vSpectrogram, app.time), file_name);
                msgbox('Saved Succesfully');
            else if strcmp(selection, 'No (Close App)')
                close(app.UIFigure)
            end
            end
        end

        % Value changed function: InitialsEditField
        function InitialsEditFieldValueChanged(app, event)
            value = app.InitialsEditField.Value;
            app.initials = value;
        end

        % Value changing function: InitialsEditField
        function InitialsEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.initials = changingValue;
        end

        % Callback function
        function ScaleSliderValueChanged(app, event)
            value = app.ScaleSlider.Value;
            app.UIAxes.XLim = [0 value];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AudioChannelDropDownLabel = uilabel(app.UIFigure);
            app.AudioChannelDropDownLabel.Text = 'Audio Channel:';
            app.AudioChannelDropDown = uidropdown(app.UIFigure);
            app.AudioChannelDropDown.Items = {'Channel 1', 'Channel 2'};
            app.AudioChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AudioChannelDropDownOpening, true);
            app.AudioChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioChannelDropDownValueChanged, true);
            app.AudioChannelDropDown.Value = 'Channel 1';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PickFileButton = uibutton(app.UIFigure, 'push');
            app.PickFileButton.ButtonPushedFcn = createCallbackFcn(app, @PickFileButtonPushed, true);
            app.PickFileButton.Text = 'Pick File';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.AngleEditField.Value = 60;
            app.MhzEditFieldLabel = uilabel(app.UIFigure);
            app.MhzEditFieldLabel.Text = 'Mhz';
            app.MhzEditField = uieditfield(app.UIFigure, 'numeric');
            app.MhzEditField.ValueChangedFcn = createCallbackFcn(app, @MhzEditFieldValueChanged, true);
            app.MhzEditField.Value = 4.3;
            app.InitialsEditFieldLabel = uilabel(app.UIFigure);
            app.InitialsEditFieldLabel.Text = 'Initials';
            app.InitialsEditField = uieditfield(app.UIFigure, 'text');
            app.InitialsEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsEditFieldValueChanged, true);
            app.InitialsEditField.ValueChangingFcn = createCallbackFcn(app, @InitialsEditFieldValueChanging, true);
            app.InitialsEditField.Value = 'XX';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audioplayerGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end