classdef velocityEnvelopeGUI_2021 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        SetDestinationPathButton   matlab.ui.control.Button
        ThresholdEditField         matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel    matlab.ui.control.Label
        InitialsEditField          matlab.ui.control.EditField
        InitialsEditFieldLabel     matlab.ui.control.Label
        MhzEditField               matlab.ui.control.NumericEditField
        MhzEditFieldLabel          matlab.ui.control.Label
        AngleEditField             matlab.ui.control.NumericEditField
        AngleEditFieldLabel        matlab.ui.control.Label
        DoneButton                 matlab.ui.control.Button
        GraphButton                matlab.ui.control.Button
        PickVideoFileButton        matlab.ui.control.Button
        StopButton                 matlab.ui.control.Button
        PlayButton                 matlab.ui.control.Button
        AudioChannelDropDown       matlab.ui.control.DropDown
        AudioChannelDropDownLabel  matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        y               
        player
        channel
        Fs
        vSpectrogram
        angle
        f_source
        sound_speed
        time
        file_path
        dest_path
        name
        initials
        threshold
        s
        f
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            set(app.UIFigure,'WindowStyle','modal')
            app.sound_speed = 1540;
            app.angle = 60;
            app.f_source = 4.3 * 1e6;
            app.threshold = 0.4;
            app.channel = 1;
            app.name = "";
            app.initials = "XX";
        end

        % Value changed function: AudioChannelDropDown
        function AudioChannelDropDownValueChanged(app, event)
            value = app.AudioChannelDropDown.Value;
            if (strcmp('Channel 1', value))
                app.channel = 1;
            else 
                app.channel = 2;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.channel = app.AudioChannelDropDown.Value;
            app.player = audioplayer(app.y(:,app.channel)*2, app.Fs);
            play(app.player);
        end

        % Button pushed function: PickVideoFileButton
        function PickVideoFileButtonPushed(app, event)
            
            app.PickVideoFileButton.Text = 'Loading...';
            
            [file, path] = uigetfile('*.mp4;*.m4a;*.mp3;*.wav;*.avi');
            
            if (file == 0)
                return
            end
            
            app.file_path = [path, file];
            app.name = file;
            [app.y, app.Fs] = audioread(app.file_path);
            app.player = audioplayer(app.y(:,1), app.Fs);
            app.PickVideoFileButton.Text = file;
            
            obj = read(VideoReader(app.file_path), 1);
            imshow(obj(:, :, :, 1))
            
            [app.s, app.f, app.time] = stft(app.y(:,app.channel),app.Fs,'Window',kaiser(256*4,5),'OverlapLength',220*4,'FFTLength',1024);
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player)
        end

        % Drop down opening function: AudioChannelDropDown
        function AudioChannelDropDownOpening(app, event)
            
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            app.f_source = app.MhzEditField.Value * 1e6;
            app.angle = app.AngleEditField.Value;
            
            app.GraphButton.Text = 'Graphing...';
            
            app.vSpectrogram = velocity_Envelope(7, app.threshold, app.s, app.f);
            app.vSpectrogram = 100*app.vSpectrogram*app.sound_speed/(2*app.f_source*cosd(app.angle));
            app.vSpectrogram = VelocityCleaner(app.vSpectrogram);
            
            plot(app.UIAxes, app.time, app.vSpectrogram)
            app.UIAxes.XScale
            app.UIAxes.Title.String = 'Velocity Envelope Over Time';
            app.UIAxes.XLabel.String = 'Time (seconds)';
            app.UIAxes.YLabel.String = 'Velocity (cm/s)';
            app.UIAxes.Toolbar.Visible = 'on';
            
            app.GraphButton.Text = 'Graph';
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            value = app.AngleEditField.Value;
            app.angle = value;
        end

        % Value changed function: MhzEditField
        function MhzEditFieldValueChanged(app, event)
            value = app.MhzEditField.Value;
            app.f_source = value * 1e6;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            fig = uifigure;
            selection = uiconfirm(fig,'Would you like to Save?','Output',...
                        'Options',{'Yes', 'No (Close App)', 'Cancel'}, ...
                        'DefaultOption', 3, 'CancelOption', 3, 'Icon','warning');
            close(fig)
            if strcmp(selection, 'Yes')
                file_name = strcat(app.dest_path, app.name(1:end-4), '_Velocity', app.initials, '.mat');
                if (isfile(file_name))
                    answer = questdlg('Found existing data, would you like to overwrite?', ...
	                    'Overwrite Data Warning', ...
	                     'Yes','No', 'No');
                    if strcmp(answer, 'No')
                        return;
                    end
                end
                
                assignin('base', 'Velocity_forRRI', app.vSpectrogram)
                assignin('base', 'time_array', transpose(app.time))
                assignin('base', 'threshold', app.threshold)
                
                q = char(39);
                
                command = strcat('save(', q, file_name, q, ')');
                
                evalin('base', command);
                
                msgbox('Saved Succesfully');
            else if strcmp(selection, 'No (Close App)')
                close(app.UIFigure)
            end
            end
        end

        % Value changed function: InitialsEditField
        function InitialsEditFieldValueChanged(app, event)
            value = app.InitialsEditField.Value;
            app.initials = value;
        end

        % Value changing function: InitialsEditField
        function InitialsEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.initials = changingValue;
        end

        % Callback function
        function ScaleSliderValueChanged(app, event)
            value = app.ScaleSlider.Value;
            app.UIAxes.XLim = [0 value];
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.threshold = value;
            if strcmp(app.PickVideoFileButton.Text, app.name)
                app.GraphButtonPushed()
            end
        end

        % Button pushed function: SetDestinationPathButton
        function SetDestinationPathButtonPushed(app, event)
            app.dest_path = strcat(uigetdir('C:\', 'Select a folder to save output'), '\');
        end

        % Callback function
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            app.threshold = value;
            if strcmp(app.PickVideoFileButton.Text, app.name)
                app.GraphButtonPushed()
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AudioChannelDropDownLabel = uilabel(app.UIFigure);
            app.AudioChannelDropDownLabel.Text = 'Audio Channel:';
            app.AudioChannelDropDown = uidropdown(app.UIFigure);
            app.AudioChannelDropDown.Items = {'Channel 1', 'Channel 2'};
            app.AudioChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AudioChannelDropDownOpening, true);
            app.AudioChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioChannelDropDownValueChanged, true);
            app.AudioChannelDropDown.Value = 'Channel 1';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PickVideoFileButton = uibutton(app.UIFigure, 'push');
            app.PickVideoFileButton.ButtonPushedFcn = createCallbackFcn(app, @PickVideoFileButtonPushed, true);
            app.PickVideoFileButton.Text = 'Pick Video File';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.AngleEditField.Value = 60;
            app.MhzEditFieldLabel = uilabel(app.UIFigure);
            app.MhzEditFieldLabel.Text = 'Mhz';
            app.MhzEditField = uieditfield(app.UIFigure, 'numeric');
            app.MhzEditField.ValueChangedFcn = createCallbackFcn(app, @MhzEditFieldValueChanged, true);
            app.MhzEditField.Value = 4.3;
            app.InitialsEditFieldLabel = uilabel(app.UIFigure);
            app.InitialsEditFieldLabel.Text = 'Initials';
            app.InitialsEditField = uieditfield(app.UIFigure, 'text');
            app.InitialsEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsEditFieldValueChanged, true);
            app.InitialsEditField.ValueChangingFcn = createCallbackFcn(app, @InitialsEditFieldValueChanging, true);
            app.InitialsEditField.Value = 'XX';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.4;
            app.SetDestinationPathButton = uibutton(app.UIFigure, 'push');
            app.SetDestinationPathButton.ButtonPushedFcn = createCallbackFcn(app, @SetDestinationPathButtonPushed, true);
            app.SetDestinationPathButton.Text = 'Set Destination Path';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2)';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3)';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4)';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5)';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '6)';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '7)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = velocityEnvelopeGUI_2021

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end