classdef velocityMatrixGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        EnvelopeSmoothingEditField  matlab.ui.control.NumericEditField
        EnvelopeSmoothingEditField_2Label  matlab.ui.control.Label
        SmallSubtraction            matlab.ui.control.Button
        SmallAddition               matlab.ui.control.Button
        LargeSubtraction            matlab.ui.control.Button
        LargeAddition               matlab.ui.control.Button
        SetDestinationPathButton    matlab.ui.control.Button
        ThresholdEditField          matlab.ui.control.NumericEditField
        ThresholdEditFieldLabel     matlab.ui.control.Label
        InitialsEditField           matlab.ui.control.EditField
        InitialsEditFieldLabel      matlab.ui.control.Label
        MhzEditField                matlab.ui.control.NumericEditField
        MhzEditFieldLabel           matlab.ui.control.Label
        AngleEditField              matlab.ui.control.NumericEditField
        AngleEditFieldLabel         matlab.ui.control.Label
        DoneButton                  matlab.ui.control.Button
        GraphButton                 matlab.ui.control.Button
        PickVideoFileButton         matlab.ui.control.Button
        StopButton                  matlab.ui.control.Button
        PlayButton                  matlab.ui.control.Button
        AudioChannelDropDown        matlab.ui.control.DropDown
        AudioChannelDropDownLabel   matlab.ui.control.Label
        UIAxes2                     matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    %Developed by Aaron Lozhkin
    %Tested on MATLAB R2023a

    properties (Access = public)
        y               
        player
        channel
        Fs
        vSpectrogram
        vEnvelope
        angle
        f_source
        sound_speed
        time
        file_path
        dest_path
        source_path
        name
        initials
        threshold
        envelopeSmoothing
        s
        s_cpu
        f
        maxIndex
        minIndex
        zeroIndex

        useGPU
        wait
    end
    
    methods (Access = private)
        
        function results = GraphHelp(app)
            
            if ishandle(app.wait)
                waitbar(0.8, app.wait, 'Graphing');
            else
                app.wait = waitbar(0, 'Graphing...');
            end
            
            app.f_source = app.MhzEditField.Value * 1e6;
            app.angle = app.AngleEditField.Value;
            
            app.vSpectrogram = abs(app.s);
            %This takes the most amount of time
%             app.vSpectrogram = velocityMatrix(app.vSpectrogram, app.threshold);
            app.vSpectrogram(app.vSpectrogram < app.threshold) = 0;

            app.vEnvelope = velocityEnvelope(floor(app.envelopeSmoothing), app.vSpectrogram, app.f);
            app.vEnvelope = 100*app.vEnvelope*app.sound_speed/(2*app.f_source*cosd(app.angle));
            app.vEnvelope = velocityCleaner(app.vEnvelope);

            app.vSpectrogram = app.vSpectrogram(app.minIndex:app.zeroIndex, :);
            
            imagesc(app.time, app.f(app.minIndex:app.zeroIndex), pow2db(app.vSpectrogram), 'Parent', app.UIAxes);
            plot(app.UIAxes2, app.time, app.vEnvelope)
            
            waitbar(1, app.wait, 'Graphing');
            close(app.wait);
            
            results = 0;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Check MATLAB version
            currentVersion = version('-release');
            requiredVersion = '2021a';
        
            if ~strcmpi(currentVersion, requiredVersion)
                selection = uiconfirm(app.UIFigure, ...
                    sprintf('This app was tested on MATLAB R%s. You are using R%s. Continue anyway?', ...
                    requiredVersion, currentVersion), ...
                    'Version Mismatch', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 'Continue', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'warning');
                
                if strcmp(selection, 'Cancel')
                    delete(app); % ✅ Close the app completely
                    return; % Stop execution but keep app open
                end
            end
            
            %% --- Toolbox Check ---
            requiredToolboxes = { ...
                'Signal Processing Toolbox'  }; %, ...
                %'Image Processing Toolbox', ...
                %'Parallel Computing Toolbox' ...
                %};
        
            installedToolboxes = {ver().Name};
            missing = setdiff(requiredToolboxes, installedToolboxes);
        
            if ~isempty(missing)
                msg = sprintf(['The following required toolboxes are missing:\n\n• %s\n\n', ...
                               'Some features may not work correctly.\nContinue anyway?'], ...
                               strjoin(missing, '\n• '));
                selection = uiconfirm(app.UIFigure, msg, ...
                    'Missing Toolboxes', ...
                    'Options', {'Continue', 'Cancel'}, ...
                    'DefaultOption', 'Continue', ...
                    'CancelOption', 'Cancel', ...
                    'Icon', 'warning');
                
                if strcmp(selection, 'Cancel')
                    uialert(app.UIFigure, 'App closed due to missing required toolboxes.', 'Closing');
                    delete(app);
                    return;
                end
            end

            
            evalin('base', 'clear');
            evalin('base', 'clear all');
            evalin('base', 'close all');
            evalin('base', 'clc');
            
            app.dest_path = 'D:\Rutgers\IHPL\Machine Learning Tests\Data\Hypertension\Data By Subject - CCA CBF\';
            app.source_path = '';
            
            set(app.UIFigure,'WindowStyle','modal');
            app.sound_speed = 1540;
            app.angle = 60;
            app.f_source = 5 * 1e6;
            app.threshold = 0.4;
            app.name = "";
            app.initials = 'AL';
            app.channel = 1;
            app.envelopeSmoothing = 7;
          
            
            app.UIAxes.Title.String = 'Audio Spectrogram';
            app.UIAxes.XLabel.String = 'Time (seconds)';
            app.UIAxes.YLabel.String = 'Frequency (Hz)';
            app.UIAxes.Toolbar.Visible = 'on';
            app.UIAxes.YDir = 'Normal';
            app.UIAxes.CLim = [-21 32];
            
            app.UIAxes2.Title.String = 'Velocity Envelope';
            app.UIAxes2.XLabel.String = 'Time (seconds)';
            app.UIAxes2.YLabel.String = 'Velocity (cm/s)';
            app.UIAxes2.Toolbar.Visible = 'on';

            app.useGPU = canUseGPU();
            
            if ~app.useGPU
                msgbox("Consider downloading Parallel Computing Toolkit for GPU support","Warning!");
            end

        end

        % Value changed function: AudioChannelDropDown
        function AudioChannelDropDownValueChanged(app, event)
            value = app.AudioChannelDropDown.Value;
            if (strcmp('Channel 1', value))
                app.channel = 1;
            else 
                app.channel = 2;
            end
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            app.player = audioplayer(app.y(:,app.channel)*2, app.Fs);
            play(app.player);
        end

        % Button pushed function: PickVideoFileButton
        function PickVideoFileButtonPushed(app, event)

            app.PickVideoFileButton.Text = 'Loading...';
            [file, path] = uigetfile(strcat(app.source_path, '*.mp4;*.m4a;*.mp3;*.wav;*.avi'));
            app.source_path = erase(path, file);
            if (file == 0)
                return
            end
            
            app.file_path = [path, file];
            app.name = file;
            [app.y, app.Fs] = audioread(app.file_path);
            app.player = audioplayer(app.y(:,1), app.Fs);
            app.PickVideoFileButton.Text = file;
            
            obj = read(VideoReader(app.file_path), Inf);
            imshow(obj(:, :, :, 1))
            
            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            stop(app.player)
        end

        % Drop down opening function: AudioChannelDropDown
        function AudioChannelDropDownOpening(app, event)
            
        end

        % Button pushed function: GraphButton
        function GraphButtonPushed(app, event)
            
            app.wait = waitbar(0, 'Graphing...');

            if app.useGPU
                sound = gpuArray(app.y(:,app.channel));
            else
                sound = app.y(:,app.channel);
            end

            [app.s, app.f, app.time] = stft(sound,app.Fs,'Window',kaiser(256*8,5),'OverlapLength',220*4,'FFTLength',1024*4);
%             [app.s, app.f, app.time] = stft(gpuArray(sound), app.Fs, 'Window', kaiser(512*4, 5), 'OverlapLength', 128*4, 'FFTLength', 512*4);
            
            waitbar(0.3, app.wait, 'Graphing');

            app.s_cpu = gather(app.s);
            app.f = gather(app.f);
            app.time = gather(app.time);
            app.maxIndex = find(app.f > 4990 & app.f < 5010, 1);
            app.zeroIndex = find(app.f == 0);
            
            app.minIndex = app.zeroIndex - (app.maxIndex - app.zeroIndex);
            
            app.UIAxes.XLim = [0 max(app.time)];
            app.UIAxes.YLim = [app.f(app.minIndex) app.f(app.zeroIndex)];

            waitbar(0.68, app.wait, 'Graphing');
            
            GraphHelp(app);
            
        end

        % Value changed function: AngleEditField
        function AngleEditFieldValueChanged(app, event)
            value = app.AngleEditField.Value;
            app.angle = value;
        end

        % Value changed function: MhzEditField
        function MhzEditFieldValueChanged(app, event)
            value = app.MhzEditField.Value;
            app.f_source = value * 1e6;
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            fig = uifigure;
            selection = uiconfirm(fig,'Would you like to Save?','Output',...
                        'Options',{'Yes', 'No (Close App)', 'Cancel'}, ...
                        'DefaultOption', 3, 'CancelOption', 3, 'Icon','warning');
            close(fig)
            if strcmp(selection, 'Yes')
                
                file_name = strcat(app.dest_path, app.name(1:end-4), '_VelocityMatrix_', app.initials, '.mat');
                
                if (isfile(file_name))
                    answer = questdlg('Found existing data, would you like to overwrite?', ...
	                    'Overwrite Data Warning', ...
	                     'Yes','No', 'No');
                    if strcmp(answer, 'No')
                        return;
                    end
                end
                
                vIndex = 100*app.f*app.sound_speed/(2*app.f_source*cosd(app.angle));
                
                assignin('base', 'VelocityMatrix', abs(app.vSpectrogram));
                assignin('base', 'VelocityMatrixDB', pow2db(abs(app.vSpectrogram)));
                assignin('base', 'VelocityEnvelope', app.vEnvelope);
                assignin('base', 'Time', transpose(app.time));
                assignin('base', 'VelocityIndex', vIndex);
                assignin('base', 'FrequencyIndex', app.f)
                
                %Repeating this for Brain Flow Analysis Script
                assignin('base', 'Velocity_forRRI', app.vEnvelope);
                assignin('base', 'time_array', transpose(app.time));
                
                assignin('base', 'angle', app.angle)
                assignin('base', 'f_source', app.f_source)
                assignin('base', 'threshold', app.threshold)
                assignin('base', 'zeroIndex', app.zeroIndex)
                assignin('base', 'minIndex', app.minIndex)
                
                q = char(39);
                
                %Resize Axes for output
                app.UIAxes.XLim = [0 max(app.time)];
                app.UIAxes.YLim = [app.f(app.minIndex) app.f(app.zeroIndex)];
                
                app.UIAxes2.XLim = [0 max(app.time)];
                app.UIAxes2.YLim = [min(0, min(app.vEnvelope)-10) max(app.vEnvelope) + 15];
                
                exportgraphics(app.UIAxes, strcat(app.dest_path, app.name(1:end-4), '_VelocityMatrixImage_', app.initials, '.jpg'))
                exportgraphics(app.UIAxes2, strcat(app.dest_path, app.name(1:end-4), '_VelocityEnvelopeImage_', app.initials, '.jpg'))
                
                
%                 command = strcat('save(', q, file_name, q, ')');
                command = strcat('save(', q, file_name, q, ',', q, '-v7.3', q, ')');
                
                evalin('base', command)
                evalin('base', 'close all');
                
%                 writematrix(app.vSpectrogram, strcat(app.dest_path, file_name1));
%                 writematrix(app.time, strcat(app.dest_path, file_name2));

                msgbox('Saved Succesfully');
                
            elseif strcmp(selection, 'No (Close App)')
                close(app.UIFigure)
            end
        end

        % Value changed function: InitialsEditField
        function InitialsEditFieldValueChanged(app, event)
            value = app.InitialsEditField.Value;
            app.initials = value;
        end

        % Value changing function: InitialsEditField
        function InitialsEditFieldValueChanging(app, event)
            changingValue = event.Value;
            app.initials = changingValue;
        end

        % Callback function
        function ScaleSliderValueChanged(app, event)
            value = app.ScaleSlider.Value;
            app.UIAxes.XLim = [0 value];
        end

        % Value changed function: ThresholdEditField
        function ThresholdEditFieldValueChanged(app, event)
            value = app.ThresholdEditField.Value;
            app.threshold = value;
            if strcmp(app.PickVideoFileButton.Text, app.name)
                GraphHelp(app);
            end
        end

        % Button pushed function: SetDestinationPathButton
        function SetDestinationPathButtonPushed(app, event)
            app.dest_path = strcat(uigetdir('D:\Rutgers\IHPL\Machine Learning Tests\Data\Hypertension\Data By Subject - CCA CBF', 'Select a folder to save output'), '\');
        end

        % Button pushed function: LargeSubtraction
        function LargeSubtractionPushed(app, event)
            if app.ThresholdEditField.Value > 0 && app.ThresholdEditField.Value >= 1
                app.ThresholdEditField.Value = app.ThresholdEditField.Value - 1;
                app.threshold = app.ThresholdEditField.Value;
            end
            
            if strcmp(app.PickVideoFileButton.Text, app.name)
                GraphHelp(app);
            end
            
        end

        % Button pushed function: SmallAddition
        function SmallAdditionButtonPushed(app, event)
            if app.ThresholdEditField.Value > 0
                app.ThresholdEditField.Value = app.ThresholdEditField.Value + 0.1;
                app.threshold = app.ThresholdEditField.Value;
            end
            
            if strcmp(app.PickVideoFileButton.Text, app.name)
                GraphHelp(app);
            end
        end

        % Button pushed function: LargeAddition
        function LargeAdditionButtonPushed(app, event)
            if app.ThresholdEditField.Value > 0
                app.ThresholdEditField.Value = app.ThresholdEditField.Value + 1;
                app.threshold = app.ThresholdEditField.Value;
            end
            
            if strcmp(app.PickVideoFileButton.Text, app.name)
                GraphHelp(app);
            end
        end

        % Button pushed function: SmallSubtraction
        function SmallSubtractionButtonPushed(app, event)
            if app.ThresholdEditField.Value > 0 && app.ThresholdEditField.Value >= 0.1
                app.ThresholdEditField.Value = app.ThresholdEditField.Value - 0.1;
                app.threshold = app.ThresholdEditField.Value;
            end
            
            if strcmp(app.PickVideoFileButton.Text, app.name)
                GraphHelp(app);
            end
        end

        % Value changed function: EnvelopeSmoothingEditField
        function EnvelopeSmoothingEditFieldValueChanged(app, event)
            value = app.EnvelopeSmoothingEditField.Value;
            if value >= 0
                app.envelopeSmoothing = value;
            end
            
            if strcmp(app.PickVideoFileButton.Text, app.name)
                GraphHelp(app);
            end
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    LargeAdditionButtonPushed(app)
                case 'leftarrow'
                    LargeSubtractionPushed(app)
                case 'period'
                    SmallAdditionButtonPushed(app)
                case 'comma'
                    SmallSubtractionButtonPushed(app)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.AudioChannelDropDownLabel = uilabel(app.UIFigure);
            app.AudioChannelDropDownLabel.Text = 'Audio Channel:';
            app.AudioChannelDropDown = uidropdown(app.UIFigure);
            app.AudioChannelDropDown.Items = {'Channel 1', 'Channel 2'};
            app.AudioChannelDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AudioChannelDropDownOpening, true);
            app.AudioChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @AudioChannelDropDownValueChanged, true);
            app.AudioChannelDropDown.Value = 'Channel 1';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Text = 'Play';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PickVideoFileButton = uibutton(app.UIFigure, 'push');
            app.PickVideoFileButton.ButtonPushedFcn = createCallbackFcn(app, @PickVideoFileButtonPushed, true);
            app.PickVideoFileButton.Text = 'Pick Video File';
            app.GraphButton = uibutton(app.UIFigure, 'push');
            app.GraphButton.ButtonPushedFcn = createCallbackFcn(app, @GraphButtonPushed, true);
            app.GraphButton.Text = 'Graph';
            app.DoneButton = uibutton(app.UIFigure, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Text = 'Done';
            app.AngleEditFieldLabel = uilabel(app.UIFigure);
            app.AngleEditFieldLabel.Text = 'Angle';
            app.AngleEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleEditField.ValueChangedFcn = createCallbackFcn(app, @AngleEditFieldValueChanged, true);
            app.AngleEditField.Value = 60;
            app.MhzEditFieldLabel = uilabel(app.UIFigure);
            app.MhzEditFieldLabel.Text = 'Mhz';
            app.MhzEditField = uieditfield(app.UIFigure, 'numeric');
            app.MhzEditField.ValueChangedFcn = createCallbackFcn(app, @MhzEditFieldValueChanged, true);
            app.MhzEditField.Value = 4.3;
            app.InitialsEditFieldLabel = uilabel(app.UIFigure);
            app.InitialsEditFieldLabel.Text = 'Initials';
            app.InitialsEditField = uieditfield(app.UIFigure, 'text');
            app.InitialsEditField.ValueChangedFcn = createCallbackFcn(app, @InitialsEditFieldValueChanged, true);
            app.InitialsEditField.ValueChangingFcn = createCallbackFcn(app, @InitialsEditFieldValueChanging, true);
            app.InitialsEditField.Value = 'XX';
            app.ThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.ThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.ThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @ThresholdEditFieldValueChanged, true);
            app.ThresholdEditField.Value = 0.4;
            app.SetDestinationPathButton = uibutton(app.UIFigure, 'push');
            app.SetDestinationPathButton.ButtonPushedFcn = createCallbackFcn(app, @SetDestinationPathButtonPushed, true);
            app.SetDestinationPathButton.Text = 'Set Destination Path';
            app.LargeAddition = uibutton(app.UIFigure, 'push');
            app.LargeAddition.ButtonPushedFcn = createCallbackFcn(app, @LargeAdditionButtonPushed, true);
            app.LargeAddition.Text = '++';
            app.LargeSubtraction = uibutton(app.UIFigure, 'push');
            app.LargeSubtraction.ButtonPushedFcn = createCallbackFcn(app, @LargeSubtractionPushed, true);
            app.LargeSubtraction.Text = '--';
            app.SmallAddition = uibutton(app.UIFigure, 'push');
            app.SmallAddition.ButtonPushedFcn = createCallbackFcn(app, @SmallAdditionButtonPushed, true);
            app.SmallAddition.Text = '+';
            app.SmallSubtraction = uibutton(app.UIFigure, 'push');
            app.SmallSubtraction.ButtonPushedFcn = createCallbackFcn(app, @SmallSubtractionButtonPushed, true);
            app.SmallSubtraction.Text = '-';
            app.EnvelopeSmoothingEditField_2Label = uilabel(app.UIFigure);
            app.EnvelopeSmoothingEditField_2Label.Text = {'Envelope '; 'Smoothing'};
            app.EnvelopeSmoothingEditField = uieditfield(app.UIFigure, 'numeric');
            app.EnvelopeSmoothingEditField.Limits = [0 50];
            app.EnvelopeSmoothingEditField.RoundFractionalValues = 'on';
            app.EnvelopeSmoothingEditField.ValueChangedFcn = createCallbackFcn(app, @EnvelopeSmoothingEditFieldValueChanged, true);
            app.EnvelopeSmoothingEditField.Value = 7;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = velocityMatrixGui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end